classdef PSTH_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        psthPlotterAppUIFigure  matlab.ui.Figure
        mainGl                  matlab.ui.container.GridLayout
        psthCountLabel          matlab.ui.control.Label
        AddPSTHButton           matlab.ui.control.Button
        LoadedDataLabel         matlab.ui.control.Label
        LoadDataButton          matlab.ui.control.Button
        PSTHplotsPanel          matlab.ui.container.Panel
        psthGl                  matlab.ui.container.GridLayout
    end

    % Properties added to app for access outside of app or inside custom UI
    % components
    properties (Access = public)
         % Empty data holder for PSTH panel handles.
        psthPlots = gobjects(0);
    end
    
    % function for closing a PSTH panel when "x" button is pressed
    methods (Access = private)
        
        
    end

    methods (Access = public)

        function updateData(app, newSpTimes, newSpkClust, newEvtTimes)
            % Append new spike times
            app.psthPlotterAppUIFigure.UserData.spikeTimes = ...
                [app.psthPlotterAppUIFigure.UserData.spikeTimes; ...
                newSpTimes];

            % Append new spike clusters and redefine "uClusterStrings":
            app.psthPlotterAppUIFigure.UserData.spikeClusters = ...
                [app.psthPlotterAppUIFigure.UserData.spikeClusters; ...
                newSpkClust];
            app.psthPlotterAppUIFigure.UserData.uClusterStrings = ...
                arrayfun(@num2str, ...
                unique(...
                app.psthPlotterAppUIFigure.UserData.spikeClusters), ...
                    'UniformOutput', false);

            % Append event times for each field
            try
            eventFieldNames = fieldnames(...
                app.psthPlotterAppUIFigure.UserData.eventTimes);
            for i = 1:numel(eventFieldNames)
                fieldName = eventFieldNames{i};
                app.psthPlotterAppUIFigure.UserData.eventTimes.(...
                    fieldName) = ...
                    [app.psthPlotterAppUIFigure.UserData.eventTimes.(...
                    fieldName); ...
                    newEvtTimes.(fieldName)];
            end
            catch me
                keyboard
            end

            % Update the PSTH plots- Loop over all PSTH components and 
            % update them:
            for i = 1:numel(app.psthPlots)
                    % update the PSTH:
                    updatePsth(app.psthPlots(i));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

            % prompt user to select MAT file:
            [file, path] = uigetfile('*.mat');

            % if user pressed cancel, skip remaining steps:
            if ~isempty(file)
                % load user-selected MAT file into "dataSet" variable:
                dataSet = load([path file]);

                % get unique cluster IDs as a cell array:
                dataSet.uClusterStrings = ...
                    arrayfun(@num2str, unique(dataSet.spikeClusters), ...
                    'UniformOutput', false);

                % store data in "UserData"
                app.psthPlotterAppUIFigure.UserData = dataSet;

                % update text label to inform user which dataset we have
                % loaded:
                app.LoadedDataLabel.Text = file;
            end

            % bring app back to front:
            figure(app.psthPlotterAppUIFigure);
        end

        % Button pushed function: AddPSTHButton
        function AddPSTHButtonPushed(app, event)
            
            % make sure we have loaded data before we create the new UI
            % component:
            if isempty(app.psthPlotterAppUIFigure.UserData)
                % if no data are loaded, report to user
                app.psthCountLabel.Text = 'Error: no dataset loaded!';
            else

                % check to see which psthPlots we actually have:
                app.psthPlots(~isgraphics(app.psthPlots)) = [];

                % get current count of PSTH plots (after creating a new 
                % one):
                nPsthPlots = length(app.psthPlots) + 1;

                % if data are loaded, create ui component:
                app.psthPlots(nPsthPlots) = psthComponent(...
                    app.psthPlotterAppUIFigure);

                % update text label to let user know how many panels have
                % been created:
                app.psthCountLabel.Text = [num2str(nPsthPlots) ...
                    ' PSTHs'];

                % place the component into a grid layout by changing it's
                % parent to that grid layout:
                set(app.psthPlots(end), 'Parent', app.psthGl);

                % adjust the grid layout based on how many PSTHs we have:
                nCols = ceil(sqrt(nPsthPlots));
                nRows = ceil(nPsthPlots/nCols);

                % reposition PSTHs in the grid so we take up empty spots:
                for i = 1:nPsthPlots
                    % use "ind2sub" to find the row & column corresponding
                    % to a given consecutive numerical index:
                    [rowIdx, colIdx] = ind2sub([nRows, nCols], i);

                    % get the component's current "GridLayoutOptions" and
                    % store in a temporary variable ("temp"):
                    temp = get(app.psthPlots(i), 'Layout');

                    % adjust "temp" based on output of ind2sub:
                    temp.Row = rowIdx;
                    temp.Column = colIdx;

                    % set "Layout" property using temp:
                    set(app.psthPlots(i), 'Layout', temp);
                end

                % This might be entirely irrelevant since I think the grid
                % layout will resize on it's own but...
                set(app.psthGl, 'RowHeight', repmat({'1x'}, 1, nRows), ...
                    'ColumnWidth', repmat({'1x'}, 1, nCols))

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.psthPlotterAppUIFigure = uifigure('Visible', 'off');
            app.psthPlotterAppUIFigure.Name = 'psthPlotterApp';
            app.mainGl = uigridlayout(app.psthPlotterAppUIFigure);
            app.PSTHplotsPanel = uipanel(app.mainGl);
            app.PSTHplotsPanel.Title = 'PSTH plots';
            app.psthGl = uigridlayout(app.PSTHplotsPanel);
            app.LoadDataButton = uibutton(app.mainGl, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadedDataLabel = uilabel(app.mainGl);
            app.LoadedDataLabel.Text = 'No Dataset Loaded';
            app.AddPSTHButton = uibutton(app.mainGl, 'push');
            app.AddPSTHButton.ButtonPushedFcn = createCallbackFcn(app, @AddPSTHButtonPushed, true);
            app.AddPSTHButton.Text = 'Add PSTH';
            app.psthCountLabel = uilabel(app.mainGl);
            app.psthCountLabel.Text = '0 PSTHs';
            % Show the figure after all components are created
            app.psthPlotterAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSTH_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.psthPlotterAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.psthPlotterAppUIFigure)
        end
    end
end