classdef psthComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        gl                           matlab.ui.container.GridLayout
        BinDurmsEditField            matlab.ui.control.NumericEditField
        BinDurmsEditFieldLabel       matlab.ui.control.Label
        ClusterIDDropDown            matlab.ui.control.DropDown
        ClusterIDDropDownLabel       matlab.ui.control.Label
        AlignmentEventDropDown       matlab.ui.control.DropDown
        AlignmentEventDropDownLabel  matlab.ui.control.Label
        MinTimeEditField             matlab.ui.control.NumericEditField
        MinTimeLabel                 matlab.ui.control.Label
        MaxTimeEditField             matlab.ui.control.NumericEditField
        MaxTimeLabel                 matlab.ui.control.Label
        XButton                      matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function updatePsth(comp)

            % define "app" (since everything is buried in grid layouts):
            app = comp.Parent.Parent.Parent.Parent;

            % define spiketimes and event times to pass to
            % "alignAndBinSpikes:
            try
            tempSpikeTimes = app.UserData.spikeTimes(...
                app.UserData.spikeClusters == ...
                round(str2double(comp.ClusterIDDropDown.Value)));
            tempEventTimes = nonzeros(...
                app.UserData.eventTimes.(...
                comp.AlignmentEventDropDown.Value));
            catch me
                keyboard
            end

            % Align and bin the event spikes.
            binFunArgs = {tempSpikeTimes, ...
                tempEventTimes, ...
                comp.MinTimeEditField.Value, ...
                comp.MaxTimeEditField.Value, ...
                comp.BinDurmsEditField.Value/1000};
            [meanBinnedRate, binCenters] = ...
                binFun(comp, binFunArgs);

            % delete existing PSTH if there is one:
            if ~isempty(comp.UIAxes.Children)
                delete(comp.UIAxes.Children);
            end

            % make new PSTH:
            bsPlot(comp, binCenters, meanBinnedRate);
        end

        function h = bsPlot(comp, x, y)

            xdiff = mean(diff(x))/2;

            xStairs = [x(:)'-xdiff, x(end)+xdiff];
            yStairs = [y(:)', y(end)];

            comp.UIAxes.NextPlot = "add";
            hInd = 1;
            h(hInd) = bar(comp.UIAxes, x, y, 1, ...
                'EdgeColor', 'None', ...
                'FaceColor', 0.75*[1 1 1]);
            hInd = hInd + 1;
            comp.UIAxes.NextPlot = "add";
            h(hInd) = stairs(comp.UIAxes, xStairs, yStairs, ...
                'Color', [0 0 0], 'LineWidth', 2);

        end
        
        function [meanBinnedRate, binCenters] = binFun(~, inArgs)
            spTimes = inArgs{1};
            evTimes = inArgs{2};
            minTime = inArgs{3};
            maxTime = inArgs{4};
            binWidth = inArgs{5};

            % round minTime / maxTime to nearest millisecond:
            minTime = round(minTime / 0.001)*0.001;
            maxTime = round(maxTime / 0.001)*0.001;

            % Define values and make variables to store outputs
            binStarts = (round(minTime/0.001)*0.001):binWidth:...
                (round((maxTime - binWidth)/0.001)*0.001);
            binEnds   = binStarts + binWidth;
            nBins = length(binStarts);
            nEventTimes = length(evTimes);
            binnedCountsArray = zeros(nEventTimes, nBins);

            % loop over event times
            for i = 1:nEventTimes

                % logically index spike times that fall within our window
                % of interest relative to the current event time:
                g = spTimes > evTimes(i) + minTime & spTimes < ...
                    evTimes(i) + maxTime;

                % Retreive and align spikes in our window:
                tempAlignedSpikes = spTimes(g) - evTimes(i);

                % store binned counts
                for j = 1:nBins
                    binnedCountsArray(i, j) = nnz(tempAlignedSpikes > ...
                        binStarts(j) & tempAlignedSpikes < binEnds(j));
                end
            end

            % compute mean binned counts and bin centers:
            meanBinnedRate = mean(binnedCountsArray)/binWidth;
            binCenters = mean([binStarts; binEnds]);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)

            % define "app"; at this stage, the component's parent is the
            % APP itself, so we use a different definition than we do down
            % below when we retreive the data to make the PSTH.
            % Alternatively to finding the app by looking at the
            % component's parent, we could use:
            % "findall(0, 'Name', 'psthPlotterApp')"
            app = comp.Parent;

            % populate "Cluster ID" dropdown menu with unique cluster IDs:
            comp.ClusterIDDropDown.Items = ...
                app.UserData.uClusterStrings;

            % populate "Alignment Event" dropdown menu with event time
            % fieldnames:
            comp.AlignmentEventDropDown.Items = fieldnames(...
                app.UserData.eventTimes);
        end

        % Button pushed function: XButton
        function XButtonPushed(comp, event)

            % delete the component
            delete(comp);
        end

        % Value changed function: MaxTimeEditField, MinTimeEditField
        function TimeEditFieldValueChanged(comp, event)

            % get the upper time limit:
            max = comp.MaxTimeEditField.Value;

            % get the lower time limit:
            min = comp.MinTimeEditField.Value;

            % set upper x-limit of axes
            comp.UIAxes.XLim = [min max];

            % recompute PSTH:
            updatePsth(comp);
        end

        % Value changed function: AlignmentEventDropDown, 
        % ...and 2 other components
        function psthElementValueChanged(comp, event)
            
            % recompute PSTH:
            updatePsth(comp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 343 265];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Visible = true;
            comp.gl = uigridlayout(comp);
            comp.gl.Tag = 'psthComponent';
            comp.UIAxes = uiaxes(comp.gl);
            xlabel(comp.UIAxes, 'Time (s)')
            ylabel(comp.UIAxes, 'Average Spike Rate (sp/s)')
            zlabel(comp.UIAxes, 'Z')
            comp.XButton = uibutton(comp.gl, 'push');
            comp.XButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @XButtonPushed, true);
            comp.XButton.Text = 'X';
            comp.MaxTimeLabel = uilabel(comp.gl);
            comp.MaxTimeLabel.Text = 'Max. Time';
            comp.MaxTimeEditField = uieditfield(comp.gl, 'numeric');
            comp.MaxTimeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TimeEditFieldValueChanged, true);
            comp.MaxTimeEditField.Value = 1;
            comp.MinTimeLabel = uilabel(comp.gl);
            comp.MinTimeLabel.Text = 'Min. Time';
            comp.MinTimeEditField = uieditfield(comp.gl, 'numeric');
            comp.MinTimeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TimeEditFieldValueChanged, true);
            comp.MinTimeEditField.Value = -0.1;
            comp.AlignmentEventDropDownLabel = uilabel(comp.gl);
            comp.AlignmentEventDropDownLabel.Text = 'Alignment Event';
            comp.AlignmentEventDropDown = uidropdown(comp.gl);
            comp.AlignmentEventDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @psthElementValueChanged, true);
            comp.ClusterIDDropDownLabel = uilabel(comp.gl);
            comp.ClusterIDDropDownLabel.Text = 'Cluster ID:';
            comp.ClusterIDDropDown = uidropdown(comp.gl);
            comp.ClusterIDDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @psthElementValueChanged, true);
            comp.BinDurmsEditFieldLabel = uilabel(comp.gl);
            comp.BinDurmsEditFieldLabel.Text = 'Bin Dur. (ms)';
            comp.BinDurmsEditField = uieditfield(comp.gl, 'numeric');
            comp.BinDurmsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @psthElementValueChanged, true);
            comp.BinDurmsEditField.Value = 20;
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end